classdef viewAndSelectData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ChannelDropDown_2Label          matlab.ui.control.Label
        ChannelDropDown_2               matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        SaveSelectionButton             matlab.ui.control.Button
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        LengthofTimeDurationsEditFieldLabel  matlab.ui.control.Label
        LengthofTimeDurationsEditField  matlab.ui.control.NumericEditField
        TimeStartPointsSpinnerLabel     matlab.ui.control.Label
        TimeStartPointsSpinner          matlab.ui.control.Spinner
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Data % loaded data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('blockdata.mat')
            load('targetBlock.mat')
            
            app.Data.time = time;
            app.Data.EKG = EKGData;
            app.Data.SpO2 = SpO2Data;
            app.Data.PArt = PArtData;
            app.Data.Capno = CapnoData;
            app.Data.Flow = FlowData;
            app.Data.PAct = PActData;
            app.Data.PPl = PPlData;
            app.Data.PAb = PAbData;
            app.Data.VolAuto = VolAutoData;
            app.Data.VolAbs = VolAbsData;
            app.Data.DateTime = DateTime;
            app.Data.ExpN = experiment;
            app.Data.BlockN = Block;
   
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            switch value
                case 'Actuator Pressure'
                    y1data = app.Data.PAct;
                    units1 = '[PSI]';
                case 'Flow'
                    y1data = app.Data.Flow;
                    units1 = '[L/s]';
                case 'Tidal Volume'
                    y1data = app.Data.VolAuto;
                    units1 = '[L]';
                case 'Absolute Volume'
                    y1data = app.Data.VolAbs;
                    units1 = '[L]';
                case 'Pleural Pressure'
                    y1data = app.Data.PPl;
                    units1 = '[cmH2O]';
                case 'Abdominal Pressure'
                    y1data = app.Data.PAb;
                    units1 = '[cmH2O]';
                case 'EKG'
                    y1data = app.Data.EKG;
                    units1 = 'PowerLab [V]';
                case 'SpO2'
                    y1data = app.Data.SpO2;
                    units1 = 'PowerLab [V]';
                case 'Arterial Pressure'
                    y1data = app.Data.PArt;
                    units1 = 'PowerLab [V]';
                case 'Capnography'
                    y1data = app.Data.Capno;
                    units1 = 'PowerLab [V]';
                    
                
            end
            app.UIAxes.YLabel.String = units1;
            app.Data.Y1Data = y1data;
            refreshPlot(app)
        end

        % Value changed function: ChannelDropDown_2
        function ChannelDropDown_2ValueChanged(app, event)
            value = app.ChannelDropDown_2.Value;
            switch value
                case 'Actuator Pressure'
                    y2data = app.Data.PAct;
                    units2 = '[PSI]';
                case 'Flow'
                    y2data = app.Data.Flow;
                    units2 = '[L/s]';
                case 'Tidal Volume'
                    y2data = app.Data.VolAuto;
                    units2 = '[L]';
                case 'Absolute Volume'
                    y2data = app.Data.VolAbs;
                    units2 = '[L]';
                case 'Pleural Pressure'
                    y2data = app.Data.PPl;
                    units2 = '[cmH2O]';
                case 'Abdominal Pressure'
                    y2data = app.Data.PAb;
                    units2 = '[cmH2O]';
                case 'EKG'
                    y2data = app.Data.EKG;
                    units2 = 'PowerLab [V]';
                case 'SpO2'
                    y2data = app.Data.SpO2;
                    units2 = 'PowerLab [V]';
                case 'Arterial Pressure'
                    y2data = app.Data.PArt;
                    units2 = 'PowerLab [V]';
                case 'Capnography'
                    y2data = app.Data.Capno;
                    units2 = 'PowerLab [V]';
            end
            app.UIAxes2.YLabel.String = units2;
            app.Data.Y2Data = y2data;
            refreshPlot(app)
        end

        % Value changed function: LengthofTimeDurationsEditField, 
        % TimeStartPointsSpinner
        function refreshPlot(app, event)
            timeDur = app.LengthofTimeDurationsEditField.Value;
            timeStart = app.TimeStartPointsSpinner.Value;
            
            
            % Start with a fresh plot
            cla(app.UIAxes)
            cla(app.UIAxes2)
            hold(app.UIAxes,'on')
            hold(app.UIAxes2,'on')
            
            % Select relevant segment of data
            xdata = app.Data.time;
            y1data = app.Data.Y1Data;
            y2data = app.Data.Y2Data;
                        
            % Plot first set of data

            app.UIAxes.Title.String = app.ChannelDropDown.Value;
            app.UIAxes.XLim = [timeStart, timeStart+timeDur];
            plot(app.UIAxes,xdata,y1data);
                    
            % Plot second set of data
            app.UIAxes2.Title.String = app.ChannelDropDown_2.Value;
            app.UIAxes2.XLim = [timeStart, timeStart+timeDur];
            plot(app.UIAxes2,xdata,y2data);
 
        end

        % Button pushed function: SaveSelectionButton
        function SaveSelectionButtonPushed(app, event)
            timeDur = app.LengthofTimeDurationsEditField.Value;
            timeStart = app.TimeStartPointsSpinner.Value;
            absTimeStart = app.Data.DateTime+seconds(timeStart); 
            datestring = datestr(absTimeStart);
            datestring(end-2) = '_';
            datestring(end-5) = '_';
            ExpN = app.Data.ExpN;
            Block = app.Data.BlockN;
            notes = app.NotesTextArea.Value;
            
            fileOutputName = strcat(sprintf('Exp %d',ExpN),sprintf('-Block %d',Block),'-Start',datestring,sprintf('-Dur %d',timeDur),'.mat');
            save(fileOutputName,'ExpN','Block',"timeDur", "timeStart",'notes')
            save('call.mat', "fileOutputName")
            
        end

        % Value changed function: NotesTextArea
        function NotesTextAreaValueChanged(app, event)
            notes = app.NotesTextArea.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ChannelDropDown_2Label = uilabel(app.LeftPanel);
            app.ChannelDropDown_2Label.Text = 'Channel';
            app.ChannelDropDown_2 = uidropdown(app.LeftPanel);
            app.ChannelDropDown_2.Items = {'none', 'Actuator Pressure', 'Flow', 'Tidal Volume', 'Absolute Volume', 'Pleural Pressure', 'Abdominal Pressure', 'EKG', 'SpO2', 'Arterial Pressure', 'Capnography', ''};
            app.ChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDown_2ValueChanged, true);
            app.ChannelDropDown_2.Value = 'none';
            app.ChannelDropDownLabel = uilabel(app.LeftPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.LeftPanel);
            app.ChannelDropDown.Items = {'none', 'Actuator Pressure', 'Flow', 'Tidal Volume', 'Absolute Volume', 'Pleural Pressure', 'Abdominal Pressure', 'EKG', 'SpO2', 'Arterial Pressure', 'Capnography', ''};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'none';
            app.SaveSelectionButton = uibutton(app.LeftPanel, 'push');
            app.SaveSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectionButtonPushed, true);
            app.SaveSelectionButton.Text = 'Save Selection';
            app.NotesTextAreaLabel = uilabel(app.LeftPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.LeftPanel);
            app.NotesTextArea.ValueChangedFcn = createCallbackFcn(app, @NotesTextAreaValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Data')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Y')
            app.LengthofTimeDurationsEditFieldLabel = uilabel(app.RightPanel);
            app.LengthofTimeDurationsEditFieldLabel.Text = 'Length of Time Duration [s]';
            app.LengthofTimeDurationsEditField = uieditfield(app.RightPanel, 'numeric');
            app.LengthofTimeDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @refreshPlot, true);
            app.LengthofTimeDurationsEditField.Value = 1200;
            app.TimeStartPointsSpinnerLabel = uilabel(app.RightPanel);
            app.TimeStartPointsSpinnerLabel.Text = 'Time Start Point [s]';
            app.TimeStartPointsSpinner = uispinner(app.RightPanel);
            app.TimeStartPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @refreshPlot, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewAndSelectData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end